classdef StimCtrlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimCtrlUIFigure             matlab.ui.Figure
        BolusTriggercontrolPanel     matlab.ui.container.Panel
        TrgDurationEditField         matlab.ui.control.NumericEditField
        secondsEditFieldLabel        matlab.ui.control.Label
        TrgNumEditField              matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        DurationPhysioMonitorLabel   matlab.ui.control.Label
        NumberAuroraLabel            matlab.ui.control.Label
        sendcustomtriggerButton      matlab.ui.control.Button
        BOLUSButton                  matlab.ui.control.Button
        RunStimulationPanel          matlab.ui.container.Panel
        BolusDelayEditField          matlab.ui.control.NumericEditField
        DelaysLabel                  matlab.ui.control.Label
        StimRepIntervalEditField     matlab.ui.control.NumericEditField
        IntervalsLabel               matlab.ui.control.Label
        PulseWidthEditField          matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudemAEditFieldLabel    matlab.ui.control.Label
        DurationEditField            matlab.ui.control.NumericEditField
        DurationsEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        STIMULATEButton              matlab.ui.control.Button
        SubsequentBolusCheckBox      matlab.ui.control.CheckBox
        SubsequentBolusLabel         matlab.ui.control.Label
        StimRepCountSpinner          matlab.ui.control.Spinner
        RepetitionsLabel             matlab.ui.control.Label
        RepeatProtocolLabel          matlab.ui.control.Label
        ActiveChannelsLabel          matlab.ui.control.Label
        Channel3CheckBox             matlab.ui.control.CheckBox
        Channel2CheckBox             matlab.ui.control.CheckBox
        Channel1CheckBox             matlab.ui.control.CheckBox
        DeviceSetupPanel             matlab.ui.container.Panel
        TriggerBoxPortDropDown       matlab.ui.control.DropDown
        TriggerBoxPortDropDownLabel  matlab.ui.control.Label
        RefreshSerialportsButton     matlab.ui.control.Button
        ConnectStimulatorButton      matlab.ui.control.Button
    end

    %% ========== TODOs ==============================================
    % catch exception if writing to serial port failes, e.g. if cable
    % disconnected. Issue UI warning!
    % 
    % Burst Protocol!

    
    % PROPERTIES ==========================================================
    
    properties (Access = public)
        nrisDataTrg = [] % 2-elemnt with time and trigger#: [secondsOfDay, trgNumber]
        bolusPrepDelay = 10; % delay between 1st trigger and actual bolus release in seconds
        bolusTrgNums = [49 50 51];
        bolusTrgDuration = 20; % duration for setting injector-trigger to high...
        % CAVE: if too short injection will be aborted?!
        logPath = fullfile(userpath,'StimCtrlGUI','logs');
        logFile
        lsloutlet

        stimObj = struct('startStim',@()fprintf('%s DUMMY: stimulation \n',datestr(datetime,'HH:MM:SS.FFF')),...
                         'sentDataToDevice',@(varargin)fprintf('%s DUMMY: stim parameters setup...\n',datestr(datetime,'HH:MM:SS.FFF')));
    end
    
    properties (Access = private)
        scomObj
        iON  = 'a';
        iOFF = 'b';
        pON  = 'x';
        pOFF = 'y';
        PreferedCOMnamePatt = '.*Serielles USB-Gerät.*'; % regexp to match prefered COM port name
        physioTrgTimer % Description
        bolusAndPhysTrgTimer

        stimulationTimer
        stimProtocolTimer
        stimulationState_ = 'disconnected';
    end
    
    properties (Dependent)
        stimulationState        
    end
    
    properties (Constant)
        % TRIGER parameter
        OutTLabel = 'Trigger_out';
        OutTName = 'Trigger';
        SerialPortParam = {9600}; % Additional parameters passed to the serialport() function        

        % STIMULATOR parameter
        SETTLING_TIME = .04; % duration in ms between after current change
        STIM_NAME = 'STG4004';
    end
    
    
    % METHODS =============================================================
    
    %% SETTER, GETTER
    methods 
        function set.scomObj(app,val)
            try
               app.scomObj.delete();                
            catch ME                
            end
            app.scomObj = val;
        end
        
        % .................................................................

        function set.stimulationState(app,state)
            state = validatestring(state,{'stopped','running','error','disconnected'});
            switch state
                case {'stopped' 'error'}
                    app.STIMULATEButton.Enable = 'on';
                case {'running','disconnected'}
                    app.STIMULATEButton.Enable = 'off';
            end
            app.stimulationState_ = state;
        end

        function state = get.stimulationState(app)
            state = app.stimulationState_;
        end
%         function val = get.scomObj(app)
%             val = app.scomObj_;
%         end


    end


    %% ##################### PUBLIC, STATIC ###############################
    methods (Access = public, Static)
    
        function startTimerAt(tObj,tStart)
            try
                startat(tObj,tStart);
            catch ME
                if strcmpi(ME.identifier,'MATLAB:timer:startdelaynegative')
                    tObj.StartDelay = 0;
                    start(tObj);
                else
                    throwAsCaller(ME);
                end
            end
        end
        
        %% ================================================================

        function [fullnames, comNums] = getCOMports()
            if ispc
                cmd = 'powershell -command "Get-WmiObject Win32_pnpentity  -Filter """Caption LIKE ''%(COM%)%''""" | Select-Object -Property Name"';
                [s,r] = system(cmd);
                if s~=0
                    warning('StimCtrlGUI:getCOMports:systemCmdFailed','Failed to execute system command to retrieve com ports.')
                    [fullnames, comNums] = getCOMportsFallback();
                else
                    fullnames = regexp(r,'(?<=\n)[^\n]*\(COM\d+\)(?=\s*\n)','match');
                    comNums = regexp(fullnames,'(?<=\(COM)\d+','match','once');
                end
            else
                % retrieving full com port names for other OS no
                % implemented yet!
                [fullnames, comNums] = getCOMportsFallback();
            end
        end
        
        %% ================================================================
                
        function [fullnames, comNums] = getCOMportsFallback()
            fullnames = serialportlist("all");
            if ~iscell(fullnames)
                fullnames = cellstr(fullnames);
            end
            comNums = regexp(fullnames,'(?<=COM)\d+','match','once');
        end
        
        %% ================================================================
        
        function comName = getMatlabCOMname(sysComPort)
            comName = regexp(sysComPort,'COM\d+','match','once');
            if ~ismember(comName,serialportlist('all'))
                warning('StimCtrlGUI:serialPort:NameUnknown2Matlab', ...
                    'COM port ''%s'' seems to be unknown to Matlab. Creating SerialPortObject may fail!', ...
                    sysComPort);
            end            
        end
        
        %% ================================================================
        
         function updateDropDown(dropdObj,newList,preferedItem)
            currentVal = dropdObj.Value;
            newValIdx = [];
            % try to maintain previous value:
            if ~isempty(currentVal)
                newValIdx = find(strcmp(newList,currentVal));
            end
            % try to set prefered value
            if isempty(newValIdx) && nargin>2
                m = regexp(newList,preferedItem,'once');
                newValIdx = find(~cellfun(@isempty,m),1);
            end
            % if new value still empty and list not empty, set to 1
            if isempty(newValIdx) && ~isempty(newList)
                newValIdx = 1;
            end
            dropdObj.Items = newList;
            if ~isempty(newValIdx)
                dropdObj.Value = newList(newValIdx);
            end
        end
    end


    %% ##################### PRIVATE ######################################
    methods (Access = private)
        

        function setNSPdataTrg(app,value)
            % OBSOLETE
            % sets object property with current time and trg value
            value = fix(value);
            t = rem(now,1)*24*60*60;
            app.nrisDataTrg = [app.nrisDataTrg; [t, value]];
        end
        % .................................................................
        
        function sendLSLtrg(app,val)
            app.lsloutlet.push_sample(val);
        end
        % .................................................................
        
        function sendNIRStrg_(app, value)
            sendLSLtrg(app,value)
            % workaround for lsl-induced aurora crash:
%             setNSPdataTrg(app,value);
        end
        % .................................................................
        
        
        function physioTrgTimerInit(app,duration)
            app.physioTrgTimer.UserData.startedAt = datetime;% use current time to get defined duration
            app.physioTrgTimer.UserData.COMlatency = write2com(app,app.pON);
            app.physioTrgTimer.UserData.duration = duration;
            app.startTimerAt(app.physioTrgTimer, datetime+seconds(duration-.5));
        end
        % .................................................................
        function physioTrgTimerFnc(app,tObj,event)
            stopAt = tObj.UserData.startedAt + seconds(tObj.UserData.duration);
            delay = stopAt-datetime-seconds(tObj.UserData.COMlatency);
            pause(seconds(delay));
            l = write2com(app,app.pOFF);
        end
        % .................................................................
        function physioTrgTimerErr(app,tObj,event)
            msg = {'There was an error while executing the physio-trigger timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Trigger Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
        end
        
        % .................................................................
        
        
        function bolusAndPhysTrgTimerFnc(app,tObj,event)
            % struct('COMlatency',[],'delay',[],'startedAt',[],'state',[]));
            % warte wenn delay noch nicht erfüllt:
            tExec = tObj.UserData.startedAt + seconds(tObj.UserData.delay);
            tWait = tExec - datetime;
            pause(seconds(tWait));
            
            tObj.UserData.startedAt = datetime; % (event.Data.time);  % <- CAVE nehmen wir die aktuelle Zeit oder die Zeit zu der der Callback in die queue kam?
            switch tObj.UserData.state
                case 'prep'
                    sendNIRStrg_(app,app.bolusTrgNums(1));
                    fprintf('%s (TCG): Bolus will be released in %.1f seconds...\n', ...
                                datestr(now,'yyyy-mm-dd HH:MM:SS'), app.bolusPrepDelay);
                    tObj.UserData.delay = app.bolusPrepDelay;
                case 'release'
                    sendNIRStrg_(app,app.bolusTrgNums(2));
                    lat = write2com(app,[app.iON app.pON]);
                    tObj.UserData.delay = max(0, app.bolusTrgDuration-lat);
                    writeLog(app,'Bolus released.');
                    fprintf('%s (TCG): Bolus released. Trigger will be low in %.1f seconds...\n', ...
                                datestr(now,'yyyy-mm-dd HH:MM:SS'), app.bolusTrgDuration-lat/2);
                case 'low'
                    sendNIRStrg_(app,app.bolusTrgNums(3));
                    lat = write2com(app,[app.iOFF app.pOFF]);
                    writeLog(app,'Bolus trigger low.');
                otherwise, warning('serious bug');
            end
        end
        % .................................................................
        function bolusAndPhysTrgTimerStopFnc(app,tObj,event)
            switch tObj.UserData.state
                case 'prep'
                    tObj.UserData.state = 'release';
                    app.startTimerAt(tObj, tObj.UserData.startedAt + seconds(tObj.UserData.delay - 1));
                case 'release'
                    tObj.UserData.state = 'low';
                    app.startTimerAt(tObj, tObj.UserData.startedAt + seconds(tObj.UserData.delay - 1));
                case 'low'
                otherwise, warning('serious bug');
            end
        end
        % .................................................................
        function bolusAndPhysTrgTimerErr(app,tObj,event)
            msg = {'There was an error while executing the bolus timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Bolus Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
        end
        
                
        % .................................................................
        

        function stimTimerFnc(app,tObj,event)
            app.stimObj.startStim();
            nInt = tObj.TasksExecuted-1;
            if nInt>0
                tObj.UserData.Intervals(nInt) = tObj.InstantPeriod;
            end
        end

        % .................................................................

        function stimTimerStopFnc(app,tObj,event)
            % check if we need to repeat the protocol

            if tObj.UserData.ProtocolRepeats > tObj.UserData.ProtocolCounts
                delay = tObj.UserData.ProtocolInterval;
                app.startTimerAt(app.stimProtocolTimer, datetime+seconds(delay));

            else
                app.stimulationState = 'stopped';
                
                % schedule Bolus if requested TODO
                if tObj.UserData.PostBolus

                    app.sendBolusAndPhysioTrg(tObj.UserData.PostBolusDelay);
                end
            end

            % write log with timings
            app.writeLog('Stimulation stopped. Pulse-intervals: %s',num2str(tObj.UserData.Intervals,' %.4f'));
        end

        % .................................................................

        function stimTimerErrFnc(app,tObj,event)
            app.stimulationState = 'error';
            msg = {'There was an error while executing the stimulation timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Stimulation Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
        end


        % .................................................................
        
        
        function protocolTimerStartFnc(app,tObj,event)
        end

        % .................................................................
        
        function protocolTimerFnc(app,tObj,event)
            app.stimulationTimer.UserData.ProtocolCounts = app.stimulationTimer.UserData.ProtocolCounts + 1;
            app.writeLog('Starting protocol iteration #%02d',app.stimulationTimer.UserData.ProtocolCounts)
%             start stimTimer
            app.stimulationTimer.start();
        end

        % .................................................................
        
        function protocolTimerStopFnc(app,tObj,event)
%             write log with parameters?
        end

        % .................................................................
        
        function protocolTimerErrFnc(app,tObj,event)
            msg = {'There was an error while executing the stimulation protocol timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Protocol Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
            app.stimulationState = 'error';
        end


        % .................................................................
        
        
        function setupStimulation(app)
            % set stim parameter from GUI to timer objects
            P = app.getStimParam();
            amplitudes_mA = [0 -P.amp/2 0 P.amp/2 0]; %mV/uA 
            amplitudes_muA = amplitudes_mA*1000;
            st = app.SETTLING_TIME;
            durations_ms = [st P.pwidth st P.pwidth st]; %duration in ms
            durations_s = durations_ms/1000;

            pt1 = mcs.stg.pulse_train.fromAmpDurationArrays(...
               amplitudes_muA,durations_s,'output_type','current');
            
            %Send the pattern to channels
            chn = app.getActiveChn();
            app.stimObj.sentDataToDevice(chn,repmat({pt1},1,numel(chn)));
                        
            pulseCnt = ceil(P.duration * P.freq);
            app.stimulationTimer.TasksToExecute = pulseCnt;
            app.stimulationTimer.Period = 1/P.freq;
            app.stimulationTimer.UserData.Intervals = NaN([pulseCnt-1,1]);

            app.writeLog('Stimulation parameters setup:\tAMP = %smA\tDURATION_PATTERN = %sms\tSTIM_DURATION = %.1fs\tCOUNT = %d\tFREQ = %.2fHz\tCHANNELS =%s', ...
                num2str(amplitudes_mA,' %.1f'), num2str(durations_ms,' %.1f'), P.duration, pulseCnt, P.freq, num2str(chn,' %d'))
        end

        % .................................................................

        function setupStimProtcol(app)
            protReps = app.StimRepCountSpinner.Value;
            protInterv = app.StimRepIntervalEditField.Value;
            postBolus = app.SubsequentBolusCheckBox.Value;
            postBolusDelay = app.BolusDelayEditField.Value - app.bolusPrepDelay;

            app.stimulationTimer.UserData = struct(...
                        'ProtocolRepeats',   protReps,...
                        'ProtocolCounts',    0, ...
                        'ProtocolInterval',  protInterv, ...
                        'PostBolus',         postBolus, ...
                        'PostBolusDelay',    postBolusDelay);

            app.writeLog('Stimulation protcol setup:\tREPETITIONS = %d\tINTERVAL = %.1fs\tBOLUS = %d\tBOLUSDELAY = %.1fs', ...
                                                            protReps, protInterv,  postBolus, postBolusDelay)
        end


        % .................................................................
        

        function runStimProtocol(app)
            app.setupStimulation();
            app.setupStimProtcol();
            app.stimulationState = 'running';
            app.stimProtocolTimer.start();
        end


        % .................................................................
        
        function updateCOMportDropDown(app,newPortList)
            app.updateDropDown(app.TriggerBoxPortDropDown,newPortList,app.PreferedCOMnamePatt);
        end
    end


    %% ##################### PUBLIC  ######################################
    methods (Access = public)
        
        function sendNIRStrg(app, value)
            writeLog(app,'Triggering NIRS.')
            sendNIRStrg_(app,value);
        end

        % .................................................................

        function sendPhysioTrg(app,duration)
            writeLog(app,'Triggering Physio.');
%             sendPhysioTrg_(app,duration);
            app.physioTrgTimerInit(duration);
        end

        % .................................................................

        function sendNIRSandPhysioTrg(app,value,duration)
            writeLog(app,'Triggering NIRS and Physio.');
            sendNIRStrg_(app,value);
%             sendPhysioTrg_(app,duration);
            app.physioTrgTimerInit(duration);
        end
        % .................................................................
        
        function sendBolusAndPhysioTrg(app,delay)
            writeLog(app,'Triggering bolus: NIRS data, physio, injector.');
            app.bolusAndPhysTrgTimer.UserData.startedAt = datetime;
            app.bolusAndPhysTrgTimer.UserData.delay = delay;
            app.bolusAndPhysTrgTimer.UserData.state = 'prep';
            app.startTimerAt(app.bolusAndPhysTrgTimer, datetime+seconds(delay-.5));
%             sendBolusAndPhysioTrg_(app);
        end

        % .................................................................
        
        function latency = write2com(app,buf)
%             latency = 0;
%             warning('!!! Triggerbox DISABELED !!!')
%             return; % <<<<<<<<<<<<<< DEBUG <<<<<<<<<<<<<<<<<
            t = tic;
            write(app.scomObj,buf,'char'); %uint8
            latency = toc(t);
            while app.scomObj.NumBytesAvailable<1
                latency = toc(t);
                if latency > 2
                    errordlg('No receipt received!','Error writing to COM port','modal')
                    app.writeLog('ERROR: No receipt from trigger box received within 2 seconds.')
                    break;
                end
            end
            
            app.writeLog('Latency was %f seconds',latency);
%             AnzBytes=app.scomObj.NumBytesAvailable;
%             Data=read(app.scomObj,AnzBytes,'char')
        end

        % .................................................................
        
        function writeLog(app,msg,varargin)
            if ~isempty(varargin)
                msg = sprintf(msg,varargin{:});
            end
            fid = fopen(app.logFile,'a');
            fprintf(fid,'%s: %s\n',datestr(now,'yy-mm-dd HH:MM:SS.FFF'), msg);
            fclose(fid);
        end

        % .................................................................
                
        function [s,po]=createScomObj(app)
            portName = app.TriggerBoxPortDropDown.Value;
            if isempty(portName)
                s=1; po=[];
            else
                if ~isempty(app.scomObj) && isprop(app.scomObj,'Port') && ...
                        strcmp(app.scomObj.Port, app.getMatlabCOMname(portName))
                    % delete obj if obj exists and port name is the same
                    app.scomObj = [];
                end
                [s,ME,po] = app.testSerialPort(portName);
                if s~=0
                    warndlg({sprintf('Failed to create serial port object with COM port %s! Check/reestablish connection!',portName), ME.message}, ...
                        'Error using COM port');
                end
            end
            app.scomObj = po;
            if ~isempty(app.scomObj)
                app.TriggerBoxPortDropDown.BackgroundColor = [0.4980    0.9412    0.3843];
            else
                app.TriggerBoxPortDropDown.BackgroundColor = [0.8588    0.3961    0.1882];
            end
        end

        % .................................................................

        function [s,ME,po] = testSerialPort(app,name)
            comName = app.getMatlabCOMname(name);
            ME = []; po = [];
            s=0;
%             try delete(app.SerialComObj); end
            try
                po = serialport(comName,app.SerialPortParam{:});
                if nargout<3, delete(po); end
            catch ME
                s=1;
            end
        end

        % .................................................................

        function actChn = getActiveChn(app)
            actChn = [];
            if app.Channel1CheckBox.Value,  actChn = 1;
            end
            if app.Channel2CheckBox.Value,  actChn = [actChn 2];
            end
            if app.Channel3CheckBox.Value,  actChn = [actChn 4];
            end
        end

        % .................................................................

        function initStimulator(app)
            try
                s = mcs.getStimulator(app.STIM_NAME);
    
                %Note: 'repeat_all', 0 sets all triggers                                                                                                                                                                                                                             to have infinite repeats (manual stim stopping required)
                %number gives repeats for all channels
                s.setupTrigger('linearize',true,'repeat_all',1); % 
                
                %Set all channels to current mode
                s.setCurrentMode();
                app.stimObj = s;
                app.ConnectStimulatorButton.BackgroundColor = [0.4980    0.9412    0.3843];

                app.stimulationState = 'stopped';

            catch ME
                uialert(app.StimCtrlUIFigure, ...
                    {'There was an error while initializing the stimulator:' ...
                     ME.message}, ...
                    'Error setting up stimulator', 'Icon','error');
                app.ConnectStimulatorButton.BackgroundColor = [0.8588    0.3961    0.1882];
                app.stimObj = [];
                app.stimulationState = 'disconnected';
                rethrow(ME);
            end
        end

        % .................................................................

        function P = getStimParam(app)
            % returns struct with fields 'amp' [mA], 'freq', 'pwidth', 'duration'
            P.amp = app.AmplitudeEditField.Value;
            P.freq = app.FrequencyEditField.Value;
            P.pwidth = app.PulseWidthEditField.Value;
            P.duration = app.DurationEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init_gui(app)
            appPth = fileparts(mfilename('fullpath'));
            addpath(appPth);
            app.RefreshSerialportsButton.Icon = fullfile(appPth,'refresh.png');


            % ---- CHECK FOR +mcs STIMULATOR INTERFACE PACKAGE -------
            try
                mcs.getStimulator();
            catch ME
                if strcmpi(ME.identifier,'MATLAB:undefinedVarOrClass')
                    uialert(app.StimCtrlUIFigure, ...
                        {'Please add path containing the +mcs folder to the matlab path.' ...
                         'Download here: <a href=''https://github.com/JimHokanson/multichannel_systems_stg_matlab''> https://github.com/JimHokanson/multichannel_systems_stg_matlab</a>'}, ...
                        'mcs package not found','Icon','warning','Interpreter','html');
                end
            end


            % ---- SET UP LSL OUTLET ---------------------------------
            if isdeployed
                lslPth = fileparts(fileparts(appPth));
                disp(['loading lsl_lib from ' lslPth])
%                 f = dir(fullfile(filepath,'liblsl-Matlab','bin'));
%                 char({f.name})
                lslib = lsl_loadlib_(fullfile(lslPth,'liblsl-Matlab','bin','lsl.dll'));
            else
                lslPth = fullfile(appPth,'liblsl-Matlab');
                addpath(genpath(lslPth));
                if ~exist('lsl_loadlib','file')
                    d = uigetdir(userpath,'Select liblsl-Matlab directory');
                    addpath(genpath(d));
                end
                lslib = lsl_loadlib();
            end
            OutTInfo = lsl_streaminfo(lslib, app.OutTName, app.OutTLabel, 1, 0, 'cf_int8', 'sdfwerr32432');
            app.lsloutlet = lsl_outlet(OutTInfo);
            if ~0, disp(['LSL outlet ''' app.OutTName ''' created.']); end

            
            % ------ SET UP COM PORT ---------------------------------
            app.updateCOMportDropDown(app.getCOMports);
            app.createScomObj();


            % ------ SET UP LogFile ---------------------------------
            if ~exist(app.logPath,'dir'), mkdir(app.logPath); end
            app.logFile = fullfile(app.logPath,sprintf('%s.txt',datestr(now,'yyyymmdd')));
            
            
            % ------ SET UP TimerObjects ------------------------------
            app.physioTrgTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'physioTriggerTimer', ...
                "ExecutionMode",           "singleShot", ...
                ..."StartFcn",                @(x,y)app.physioTrgTimerInit(x,y),...
                "TimerFcn",                @(x,y)app.physioTrgTimerFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.physioTrgTimerErr(x,y), ...
               "UserData",                struct('COMlatency',[],'duration',[],'startedAt',[]));
            app.bolusAndPhysTrgTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'bolusAndPhysTrgTimer', ...
                "ExecutionMode",           "singleShot", ...
                "TimerFcn",                @(x,y)app.bolusAndPhysTrgTimerFnc(x,y), ...
                "StopFcn",                 @(x,y)app.bolusAndPhysTrgTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.bolusAndPhysTrgTimerErr(x,y), ...
                "UserData",                struct('COMlatency',[],'delay',[],'startedAt',[],'state',[]));
            app.stimulationTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'stimulationTimer', ...
                "ExecutionMode",           "fixedRate", ...
                "TimerFcn",                @(x,y)app.stimTimerFnc(x,y), ...
                "StopFcn",                 @(x,y)app.stimTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.stimTimerErrFnc(x,y), ...
                "UserData",                struct('ProtocolRepeats',1,'ProtocolCounts',0,'PostBolus',true,'state',[]));
            app.stimProtocolTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'stimProtocolTimer', ...
                "ExecutionMode",           "singleShot", ...
                "TimerFcn",                @(x,y)app.protocolTimerFnc(x,y), ...
                "StartFcn",                @(x,y)app.protocolTimerStartFnc(x,y), ...
                "StopFcn",                 @(x,y)app.protocolTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.protocolTimerErrFnc(x,y), ...
                "UserData",                struct('state','stopped'));
            

            % ------ ADAPT SOME GUI OBJECTS ----------------------------
            app.BolusDelayEditField.Limits(1) = app.bolusPrepDelay;

        end

        % Button pushed function: BOLUSButton
        function bolus_init(app, event)
            answer = inputdlg({'Delay in seconds:'},'Trigger BOLUS',[1,35],{'0'});
            if isempty(answer)
                return;
            else
                tdelay = str2double(answer{1});
                if ~isempty(tdelay) && isfinite(tdelay)
                    fprintf('%s (TCG): Waiting for %d seconds before bolus init...\n', ...
                        datestr(now,'yyyy-mm-dd HH:MM:SS'), tdelay(1));
%                     pause(tdelay(1));
                else
                    warndlg('Failed to interpret entered delay! Bolus wont be initiated.');
                    return;
                end
            end
            sendBolusAndPhysioTrg(app,tdelay);
        end

        % Button pushed function: sendcustomtriggerButton
        function sendCustomTrg(app, event)
            nirsTrgNum = app.TrgNumEditField.Value;
            physTrgDur = app.TrgDurationEditField.Value;
            if nirsTrgNum>0 && physTrgDur>0
                msg = sprintf('Sending trigger %d to NIRS and setting Physio-Trigger to high for %.1f seconds.',nirsTrgNum,physTrgDur);
                fncHndl = @()app.sendNIRSandPhysioTrg(nirsTrgNum,physTrgDur);
            elseif nirsTrgNum>0
                msg = sprintf('Sending trigger %d to NIRS.',nirsTrgNum);
                fncHndl = @()app.sendNIRStrg(nirsTrgNum);
            elseif physTrgDur>0
                msg = sprintf('Setting Physio-Trigger to high for %.1f seconds.',physTrgDur);
                fncHndl = @()app.sendPhysioTrg(physTrgDur);
            else
                msgbox('No trigger to send.');
                return;
            end
            answer = uiconfirm(app.StimCtrlUIFigure, ...
                msg, 'Confirm custom trigger');
            if strcmpi(answer,'ok')
                fncHndl();
            end
        end

        % Close request function: StimCtrlUIFigure
        function figDestructor(app, event)
%             fclose(app.logfid)
            try app.SerialComObj.delete(); end
            th = [app.stimulationTimer,app.stimProtocolTimer,app.physioTrgTimer,app.bolusAndPhysTrgTimer];
            try delete(th); end
            delete(app)

        end

        % Button pushed function: RefreshSerialportsButton
        function RefreshSerialportsButtonPushed(app, event)
            app.updateCOMportDropDown(app.getCOMports);
            app.createScomObj();
        end

        % Value changed function: TriggerBoxPortDropDown
        function TriggerBoxPortDropDownValueChanged(app, event)
            app.createScomObj();
        end

        % Button pushed function: ConnectStimulatorButton
        function ConnectStimulatorButtonPushed(app, event)
            app.initStimulator();
        end

        % Button pushed function: STIMULATEButton
        function STIMULATEButtonPushed(app, event)
            app.runStimProtocol();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimCtrlUIFigure = uifigure('Visible', 'off');
            app.StimCtrlUIFigure.Name = 'Stimulation Control';
            app.StimCtrlUIFigure.CloseRequestFcn = createCallbackFcn(app, @figDestructor, true);
            app.DeviceSetupPanel = uipanel(app.StimCtrlUIFigure);
            app.DeviceSetupPanel.Title = 'Device Setup';
            app.ConnectStimulatorButton = uibutton(app.DeviceSetupPanel, 'push');
            app.ConnectStimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectStimulatorButtonPushed, true);
            app.ConnectStimulatorButton.Text = 'Connect Stimulator';
            app.RefreshSerialportsButton = uibutton(app.DeviceSetupPanel, 'push');
            app.RefreshSerialportsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSerialportsButtonPushed, true);
            app.RefreshSerialportsButton.Tooltip = {'Refresh list of COM ports'};
            app.RefreshSerialportsButton.Text = '';
            app.TriggerBoxPortDropDownLabel = uilabel(app.DeviceSetupPanel);
            app.TriggerBoxPortDropDownLabel.Text = 'Trigger-Box Port:';
            app.TriggerBoxPortDropDown = uidropdown(app.DeviceSetupPanel);
            app.TriggerBoxPortDropDown.Items = {};
            app.TriggerBoxPortDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerBoxPortDropDownValueChanged, true);
            app.TriggerBoxPortDropDown.Tooltip = {'Choose COM port to which TriggerBox is connected to. See Device Manager (Geräte Manager), name should be ''Serielles USB-Geraet'''};
            app.TriggerBoxPortDropDown.Value = {};
            app.RunStimulationPanel = uipanel(app.StimCtrlUIFigure);
            app.RunStimulationPanel.Title = 'Run Stimulation';
            app.Channel1CheckBox = uicheckbox(app.RunStimulationPanel);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.RunStimulationPanel);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.RunStimulationPanel);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.ActiveChannelsLabel = uilabel(app.RunStimulationPanel);
            app.ActiveChannelsLabel.Text = 'Active Channels:';
            app.RepeatProtocolLabel = uilabel(app.RunStimulationPanel);
            app.RepeatProtocolLabel.Text = 'Repeat Protocol:';
            app.RepetitionsLabel = uilabel(app.RunStimulationPanel);
            app.RepetitionsLabel.Text = 'Repetitions:';
            app.StimRepCountSpinner = uispinner(app.RunStimulationPanel);
            app.StimRepCountSpinner.Limits = [1 Inf];
            app.StimRepCountSpinner.ValueDisplayFormat = '%.0f';
            app.StimRepCountSpinner.Value = 1;
            app.SubsequentBolusLabel = uilabel(app.RunStimulationPanel);
            app.SubsequentBolusLabel.Text = 'Subsequent Bolus:';
            app.SubsequentBolusCheckBox = uicheckbox(app.RunStimulationPanel);
            app.SubsequentBolusCheckBox.Text = '';
            app.SubsequentBolusCheckBox.Value = true;
            app.STIMULATEButton = uibutton(app.RunStimulationPanel, 'push');
            app.STIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @STIMULATEButtonPushed, true);
            app.STIMULATEButton.Text = 'STIMULATE';
            app.FrequencyHzEditFieldLabel = uilabel(app.RunStimulationPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz):';
            app.FrequencyEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.FrequencyEditField.LowerLimitInclusive = 'off';
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.Tooltip = {'Pulse rate'};
            app.FrequencyEditField.Value = 10;
            app.DurationsEditFieldLabel = uilabel(app.RunStimulationPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s):';
            app.DurationEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.DurationEditField.LowerLimitInclusive = 'off';
            app.DurationEditField.Limits = [0 Inf];
            app.DurationEditField.Tooltip = {'Duration of stimulation'};
            app.DurationEditField.Value = 5;
            app.AmplitudemAEditFieldLabel = uilabel(app.RunStimulationPanel);
            app.AmplitudemAEditFieldLabel.Text = 'Amplitude (mA):';
            app.AmplitudeEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.AmplitudeEditField.LowerLimitInclusive = 'off';
            app.AmplitudeEditField.Limits = [0 Inf];
            app.AmplitudeEditField.Tooltip = {'Current will be first set to -x/2 and then to +x/2'};
            app.AmplitudeEditField.Value = 6;
            app.PulseWidthmsEditFieldLabel = uilabel(app.RunStimulationPanel);
            app.PulseWidthmsEditFieldLabel.Text = 'Pulse Width (ms):';
            app.PulseWidthEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.PulseWidthEditField.LowerLimitInclusive = 'off';
            app.PulseWidthEditField.Limits = [0 Inf];
            app.PulseWidthEditField.Tooltip = {'Time from start of negative current to end of positive current'};
            app.PulseWidthEditField.Value = 1;
            app.IntervalsLabel = uilabel(app.RunStimulationPanel);
            app.IntervalsLabel.Text = 'Interval (s):';
            app.StimRepIntervalEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.StimRepIntervalEditField.Limits = [0 Inf];
            app.StimRepIntervalEditField.Tooltip = {'Pause between end of stimulation and onset of next stimulation'};
            app.DelaysLabel = uilabel(app.RunStimulationPanel);
            app.DelaysLabel.Text = 'Delay (s):';
            app.BolusDelayEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.BolusDelayEditField.Limits = [10 Inf];
            app.BolusDelayEditField.Tooltip = {'Delay after end of stimulation until bolus is released.'; 'The minimum is defined by the bolus preparation delay.'};
            app.BolusDelayEditField.Value = 10;
            app.BolusTriggercontrolPanel = uipanel(app.StimCtrlUIFigure);
            app.BolusTriggercontrolPanel.Title = 'Bolus & Trigger control';
            app.BOLUSButton = uibutton(app.BolusTriggercontrolPanel, 'push');
            app.BOLUSButton.ButtonPushedFcn = createCallbackFcn(app, @bolus_init, true);
            app.BOLUSButton.Text = 'BOLUS';
            app.sendcustomtriggerButton = uibutton(app.BolusTriggercontrolPanel, 'push');
            app.sendcustomtriggerButton.ButtonPushedFcn = createCallbackFcn(app, @sendCustomTrg, true);
            app.sendcustomtriggerButton.Text = 'send custom trigger:';
            app.NumberAuroraLabel = uilabel(app.BolusTriggercontrolPanel);
            app.NumberAuroraLabel.Text = 'Number (Aurora):';
            app.DurationPhysioMonitorLabel = uilabel(app.BolusTriggercontrolPanel);
            app.DurationPhysioMonitorLabel.Text = 'Duration (PhysioMonitor):';
            app.Label = uilabel(app.BolusTriggercontrolPanel);
            app.Label.Text = '[1-255]';
            app.TrgNumEditField = uieditfield(app.BolusTriggercontrolPanel, 'numeric');
            app.secondsEditFieldLabel = uilabel(app.BolusTriggercontrolPanel);
            app.secondsEditFieldLabel.Text = ' seconds';
            app.TrgDurationEditField = uieditfield(app.BolusTriggercontrolPanel, 'numeric');
            % Show the figure after all components are created
            app.StimCtrlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StimCtrlGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StimCtrlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @init_gui)
            else

                % Focus the running singleton app
                figure(runningApp.StimCtrlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimCtrlUIFigure)
        end
    end
end