classdef StimPHDultraCtrl_ProtocolBatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimCtrl_ProtocollatchUIFigure  matlab.ui.Figure
        PauseBatchButton                matlab.ui.control.Button
        LoadProtocolBatchFileButton     matlab.ui.control.Button
        StopBatchButton                 matlab.ui.control.Button
        RunBatchButton                  matlab.ui.control.Button
        BatchPanel                      matlab.ui.container.Panel
        BatchTable                      matlab.ui.control.Table
        StatusLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        statusFreezeUntil = datetime % datetime until when the status timer is stopped

        pauseDateTime datetime
        pausedAtIdx (1,1) double = NaN
    end
    
    properties (SetAccess = private)
        stimulateCB % Callback to execute stimulation
        bolusCB % Callback to trigger bolus

        batchItemTimer % timer to execute next item on batch
        statusTimer % timer to update status

        batchItemTable table % table holding the batch items
        batchT0 (1,1) datetime % datetime of batch start for timer reference
        batchItemIdx (1,1) double {mustBePositive, mustBeInteger} = 1 % index of current item on batch

        mainGUIobject % handle to the main gui app object

    end
    
    properties (Constant)
        TYPE_STRINGS = struct('bolus',  'bolus', ...
                              'train',  'pulse train', ...
                              'burst',  'pulse burst');
        BATCH_VARNAMES = ["Time", "Type", "Amplitude", "PulseWidth", "Duration", "Freq", "BurstPulseCount", "BurstFreq", "Channels"];
    end


    methods (Access = public)
        
        %% ============== BATCH TIMER CALLBACKS ===========================

        function batchItemTimerStartFnc(app,tObj,event)
            mgo = app.mainGUIobject;
            mgo.StimulationDelayEditField.Value = 0;
            mgo.ProtocolRepeatsSpinner.Value = 1;
            mgo.StimBolusCheckBox.Value = false;

            batchItem = app.batchItemTable(app.batchItemIdx,:);

            removeStyle(app.BatchTable);
            styl = uistyle("BackgroundColor",'#fcf677','FontWeight','bold');
            addStyle(app.BatchTable,styl,'row',app.batchItemIdx);

            P = mgo.stimParameter;
            
            switch lower(batchItem.Type)
                case app.TYPE_STRINGS.bolus
                    tObj.UserData.itemCB = app.bolusCB;
                    tObj.UserData.itemRefractPeriod = 50;
                    tObj.UserData.statusStrng = 'BOLUS';

                case app.TYPE_STRINGS.train
                    tObj.UserData.itemCB = app.stimulateCB;
                    P.mode          = 'train';
                    P.train.Amplitude     = batchItem.Amplitude;
                    P.train.Frequency     = batchItem.Freq;
                    P.train.PulseWidth    = batchItem.PulseWidth;
                    P.train.StimDuration  = batchItem.Duration;
                    mgo.setActiveChn(batchItem.Channels{1});
                    tObj.UserData.itemRefractPeriod = batchItem.Duration+2;
                    tObj.UserData.statusStrng = 'PULSE TRAIN';

                case app.TYPE_STRINGS.burst
                    tObj.UserData.itemCB = app.stimulateCB;
                    P.mode          = 'burst';
                    P.burst.Amplitude     = batchItem.Amplitude;
                    P.burst.Frequency     = batchItem.Freq;
                    P.burst.PulseWidth    = batchItem.PulseWidth;
                    P.burst.StimDuration  = batchItem.Duration;
                    P.burst.PulseCount    = batchItem.BurstPulseCount;
                    P.burst.BurstFreq     = batchItem.BurstFreq;
                    mgo.setActiveChn(batchItem.Channels{1});
                    tObj.UserData.itemRefractPeriod = batchItem.Duration+2;
                    tObj.UserData.statusStrng = 'PULSE BURST';

                otherwise, error('Unexpected type in batch item %d',app.batchItemIdx);
            end

            mgo.stimParameter = P;
            mgo.RunStimulationPanel.Enable = "off";
            drawnow;
        end

        % .................................................................

        function batchItemTimerFnc(app,tObj,event)
            % stop statusTimer, set Status and run itemCallback
            itemFreezeTime = datetime + seconds(tObj.UserData.itemRefractPeriod);
            stop(app.statusTimer);

            if app.statusFreezeUntil < itemFreezeTime
                app.statusFreezeUntil = itemFreezeTime;
                batchTime = datetime - app.batchT0;
                batchTime.Format = 'mm:ss';
                app.StatusLabel.Text = sprintf('%s: %s RUNNING',batchTime, tObj.UserData.statusStrng);
                % run status update again when current item is over, round to ms to avoid precision warning
            end

            tObj.UserData.itemCB();
            drawnow();
        end

        % .................................................................
        
        function batchItemTimerStopFnc(app,tObj,event)
            % increment batchItemIdx and check if there are more items on
            % the batch and start timer again or enable Run Stimulation
            % Panel again
            app.batchItemIdx = app.batchItemIdx + 1;
            if size(app.batchItemTable,1) >= app.batchItemIdx
                % more items to run
                startTime = app.batchT0 + app.batchItemTable.Time(app.batchItemIdx);
                tObj.StartDelay = max(0,round(seconds(startTime-datetime), 3));
                start(tObj);
            elseif isnan(app.pausedAtIdx)
                % batch complete
                app.StopBatchButton.Enable = "off";
                app.PauseBatchButton.Enable = "off";
            end
        end

        % .................................................................
        
        function batchItemTimerErrFnc(app,tObj,event)
            msg = {'There was an error while executing the batch item timer function:' ...
                event.Data.message};
            uialert(app.StimCtrl_ProtocollatchUIFigure, ...
                msg, ...
                'Protocol Error','Icon','error');
            app.mainGUIobject.writeLog('ERROR: %s',strjoin(msg));
            % enable Run Stimulation Panel again
            app.mainGUIobject.RunStimulationPanel.Enable = "on";
        end


        %% ============== STATUS TIMER CALLBACKS ==========================
        
        
        function statusTimerStartFnc(app,tObj,event)
            
        end

        % .................................................................
        
        function statusTimerFnc(app,tObj,event)
            if app.batchItemIdx > size(app.batchItemTable,1)
                stop(tObj);
            else
                startTime = app.batchT0 + app.batchItemTable.Time(app.batchItemIdx);
                cntDown = datetime - startTime;
                cntDown.Format = 'mm:ss';
                batchTime = datetime - app.batchT0;
                batchTime.Format = 'mm:ss';
                app.StatusLabel.Text = sprintf('%s: %s at t = %s', ...
                    batchTime, app.batchItemTable.Type(app.batchItemIdx), cntDown);
            end

        end

        % .................................................................
        
        function statusTimerStopFnc(app,tObj,event)
            if ~isnan(app.pausedAtIdx)
            elseif app.batchItemIdx > size(app.batchItemTable,1)
                batchTime = datetime - app.batchT0;
                batchTime.Format = 'mm:ss';

                app.StatusLabel.Text = sprintf('%s: BATCH COMPLETE',batchTime);
                app.mainGUIobject.writeLog('Finished processing items on batch.');
                app.mainGUIobject.RunStimulationPanel.Enable = "on";
                app.StopBatchButton.Enable = "off";
                app.PauseBatchButton.Enable = "off";
                app.RunBatchButton.Enable = "on";
                removeStyle(app.BatchTable);
            else
                tObj.StartDelay = max(0,round(seconds(app.statusFreezeUntil - datetime),3));
                start(tObj);
            end
        end

        % .................................................................
        
        function statusTimerErrFnc(app,tObj,event)
            msg = {'There was an error while executing the status timer function:' ...
                event.Data.message};
            uialert(app.StimCtrl_ProtocollatchUIFigure, ...
                msg, ...
                'Status Error','Icon','error');
            app.mainGUIobject.writeLog('ERROR: %s',strjoin(msg));
            app.StatusLabel.Text = 'Status update ERROR';
        end


        %% ============== BATCH TABLE HANDLING ============================
        
        function loadBatchFile(app,batchFile)
            if any(endsWith(batchFile,{'.xls','xlsx'},'IgnoreCase',true))
                itemTable = readProtocBatchXLS(batchFile);
            else
                itemTable = readProtocBatchCSV(batchFile);
            end
            nItm = size(itemTable,1);
            itemTable = [table((1:nItm).','VariableNames',{'#'}) itemTable];
            app.BatchTable.ColumnName = itemTable.Properties.VariableNames;
            app.BatchTable.ColumnName = strrep(app.BatchTable.ColumnName,'Time','Time [mm:ss]');

            itemTable.Time = duration(itemTable.Time,"Format",'mm:ss');
            app.BatchTable.Data = itemTable;
            itemTable.Channels = cellfun(@str2num,itemTable.Channels,'UniformOutput',false);

            try app.sanitizeBatchItemTable(itemTable)
            catch ME
                app.RunBatchButton.Enable = "off";
                uialert(app.StimCtrl_ProtocollatchUIFigure, ...
                        ME.message,'Error in batch item table!');
                rethrow(ME);
            end

            app.batchItemTable = itemTable;
            app.RunBatchButton.Enable = "on";
            app.StopBatchButton.Enable = "off";
            app.PauseBatchButton.Enable = "off";

            app.mainGUIobject.writeLog('Batch file loaded: %s',batchFile);
        end

        % .................................................................
        
        function sanitizeBatchItemTable(app,itemTable)
            assert(all(itemTable.Time>=0) && all(diff(itemTable.Time)>0), ...
                'StimCtrlBatch:sanitizeBatch:timeMustBePositiveIncreasing', ...
                'Time must be non-negative and increasing!');

            tStrngs = cellfun(@(x)getfield(app.TYPE_STRINGS,x),fieldnames(app.TYPE_STRINGS),'UniformOutput',false);
            assert(all(ismember(lower(itemTable.Type),tStrngs)), ...
                'StimCtrlBatch:sanitizeBatch:invalidItemType', ...
                'Invalid item type! Must be one of: "%s"',strjoin(tStrngs,'", "'));

            % check channels
            validateFH = @(x)cellfun(@(y)~isempty(y) & all(ismember(y,1:3)), x);
            c = "Channels";
            r = strcmpi(itemTable.Type,app.TYPE_STRINGS.train) | ...
                strcmpi(itemTable.Type,app.TYPE_STRINGS.burst);
            items = table2cell(itemTable(r,c));
            assert(all(validateFH(items),'all'), ...
                'StimCtrlBatch:sanitizeBatch:invalidChannels', ...
                'Invalid channels for stimulation! Must be non-empty and members of [1 2 3]');

            % check train parameter
            validateFH = @(x)cellfun(@(y)numel(y)==1 & all(y>0), x);
            c = ["Amplitude", "PulseWidth", "Duration", "Freq"];
            r = strcmpi(itemTable.Type,app.TYPE_STRINGS.train);
            items = table2cell(itemTable(r,c));
            assert(all(validateFH(items),'all'), ...
                'StimCtrlBatch:sanitizeBatch:invalidPulsetrainParameter', ...
                'Invalid parameter for pulse train!');

            % check burst parameters
            c = [c "BurstPulseCount", "BurstFreq"];
            r = strcmpi(itemTable.Type,app.TYPE_STRINGS.burst);
            items = table2cell(itemTable(r,c));
            assert(all(validateFH(items),'all'), ...
                'StimCtrlBatch:sanitizeBatch:invalidPulseburstParameter', ...
                'Invalid parameter for pulse burst!');
        end


        %% ================= PROCESS CONTROL ==============================

        function stopBatchProcess(app)
            app.pausedAtIdx = NaN;
            app.batchItemIdx = size(app.batchItemTable,1)+1;
            stop(app.batchItemTimer);
            stop(app.statusTimer);

            app.mainGUIobject.writeLog('Batch processing stopped.');
            batchTime = datetime - app.batchT0;
            batchTime.Format = 'mm:ss';
            app.StatusLabel.Text = sprintf('%s: BATCH STOPPED',batchTime);

            app.RunBatchButton.Enable = "on";
            app.StopBatchButton.Enable = "off";
            app.PauseBatchButton.Enable = "off";
        end

        % .................................................................
        
        function pauseBatchProcess(app)
            app.pausedAtIdx = app.batchItemIdx;
            app.pauseDateTime = datetime;
            app.batchItemIdx = size(app.batchItemTable,1)+1;

            stop(app.batchItemTimer);
            stop(app.statusTimer);

            app.mainGUIobject.writeLog('Batch processing paused.');
            batchTime = datetime - app.batchT0;
            batchTime.Format = 'mm:ss';
            app.StatusLabel.Text = sprintf('%s: BATCH PAUSED',batchTime);

            app.PauseBatchButton.Text = "Resume";
            % app.StopBatchButton.Enable = "off";
        end

        % .................................................................
        
        function resumeBatchProcess(app)
            app.batchItemIdx = app.pausedAtIdx;
            pauseDuration = (datetime-app.pauseDateTime);
            app.batchT0 = app.batchT0 + pauseDuration;
            app.pausedAtIdx = NaN;

            startDelay = seconds(app.statusFreezeUntil - datetime);
            app.statusTimer.StartDelay = max(0,round(startDelay,3));
            start(app.statusTimer);


            startTime = app.batchItemTable.Time(app.batchItemIdx);
            startDelay = seconds((app.batchT0+startTime) - datetime);
            if startDelay<0, warning('StartDelay = %.1fs at resume'); end
            app.batchItemTimer.StartDelay = max(0,round(startDelay,3));
            start(app.batchItemTimer);

            app.mainGUIobject.writeLog('Batch processing resumed.');
            batchTime = datetime - app.batchT0;
            batchTime.Format = 'mm:ss';
            app.StatusLabel.Text = sprintf('%s: BATCH RESUMED',batchTime);

            app.PauseBatchButton.Text = "Pause";
            % app.StopBatchButton.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainGUIobject)

            app.mainGUIobject = mainGUIobject;

            app.stimulateCB = @mainGUIobject.runStimProtocol;
            app.bolusCB = @()mainGUIobject.sendBolusAndPhysioTrg(0);

            app.batchItemTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'batchItemTimer', ...
                "ExecutionMode",           "singleShot", ...
                "TimerFcn",                @(x,y)app.batchItemTimerFnc(x,y), ...
                "StartFcn",                @(x,y)app.batchItemTimerStartFnc(x,y), ...
                "StopFcn",                 @(x,y)app.batchItemTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.batchItemTimerErrFnc(x,y), ...
                "UserData",                struct('itemCB',      @()disp([]), ...
                                                  'statusStrng', ''));

            app.statusTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'statusTimer', ...
                "ExecutionMode",           "fixedRate", ...
                "Period",                   2, ...
                "TimerFcn",                @(x,y)app.statusTimerFnc(x,y), ...
                "StartFcn",                @(x,y)app.statusTimerStartFnc(x,y), ...
                "StopFcn",                 @(x,y)app.statusTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.statusTimerErrFnc(x,y), ...
                "UserData",                []);

        end

        % Button pushed function: LoadProtocolBatchFileButton
        function LoadProtocolBatchFileButtonPushed(app, event)
            [f,p] = uigetfile({'*.csv;*.xls;*.xlsx'},'Select batch file',app.mainGUIobject.batchFilePath);
            if ~f, return; end
            app.mainGUIobject.batchFilePath = p;
            batchFile = fullfile(p,f);
            app.BatchPanel.Title = fullfile(batchFile);
            app.loadBatchFile(batchFile);
        end

        % Button pushed function: StopBatchButton
        function StopBatchButtonPushed(app, event)
            r = uiconfirm(app.StimCtrl_ProtocollatchUIFigure,'Stop stimulation batch?','Confirm', ...
                'Options',{'Yes','No'}, ...
                'DefaultOption',2,'CancelOption',2);
            if ~strcmpi(r,'yes'), return; end

            app.stopBatchProcess();

        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            app.batchT0 = datetime;
            app.pausedAtIdx = NaN;

            app.batchItemIdx = 1;
            app.statusTimer.StartDelay = 0;
            start(app.statusTimer);

            startDelay = seconds(app.batchItemTable.Time(1));
            app.batchItemTimer.StartDelay = startDelay;
            start(app.batchItemTimer);
            
            app.mainGUIobject.writeLog('Batch processing started.');
            app.RunBatchButton.Enable = "off";
            app.PauseBatchButton.Enable = "on";
            app.StopBatchButton.Enable = "on";
            
        end

        % Close request function: StimCtrl_ProtocollatchUIFigure
        function StimCtrl_ProtocollatchUIFigureCloseRequest(app, event)
            if ~isempty(app.batchItemTimer) ...
                    && isprop(app.batchItemTimer,'Running') ...
                    && ~strcmpi(app.batchItemTimer.Running,'off')
                r = uiconfirm(app.StimCtrl_ProtocollatchUIFigure,'Closing the window will stop the batch processing! Continue?','Confirm close', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption',2,'CancelOption',2);
                if ~strcmpi(r,'yes'), return; end
                try app.stopBatchProcess(); end
            end
            
            try delete(app.batchItemTimer); end
            try delete(app.statusTimer); end
            try app.mainGUIobject.RunStimulationPanel.Enable = "on"; end
            delete(app)
            
        end

        % Button pushed function: PauseBatchButton
        function PauseBatchButtonPushed(app, event)
            if isnan(app.pausedAtIdx)
                app.pauseBatchProcess();
            else
                app.resumeBatchProcess();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimCtrl_ProtocollatchUIFigure = uifigure('Visible', 'off');
            app.StimCtrl_ProtocollatchUIFigure.Name = 'StimCtrl Batch Processor';
            app.StimCtrl_ProtocollatchUIFigure.CloseRequestFcn = createCallbackFcn(app, @StimCtrl_ProtocollatchUIFigureCloseRequest, true);
            app.StatusLabel = uilabel(app.StimCtrl_ProtocollatchUIFigure);
            app.StatusLabel.Text = 'Status';
            app.BatchPanel = uipanel(app.StimCtrl_ProtocollatchUIFigure);
            app.BatchPanel.Title = 'FileName';
            app.BatchTable = uitable(app.BatchPanel);
            app.BatchTable.ColumnName = {'#'; 'Time [mm:ss]'; 'Type'; 'Channels'; 'Amplitude'; 'PulseWidth'; 'Duration'; 'Freq'; 'BurstPulses'; 'BurstFreq'};
            app.BatchTable.RowName = {};
            app.BatchTable.SelectionType = 'row';
            app.BatchTable.Multiselect = 'off';
            app.RunBatchButton = uibutton(app.StimCtrl_ProtocollatchUIFigure, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Enable = 'off';
            app.RunBatchButton.Text = 'Run Batch';
            app.StopBatchButton = uibutton(app.StimCtrl_ProtocollatchUIFigure, 'push');
            app.StopBatchButton.ButtonPushedFcn = createCallbackFcn(app, @StopBatchButtonPushed, true);
            app.StopBatchButton.Enable = 'off';
            app.StopBatchButton.Text = 'Stop Batch';
            app.LoadProtocolBatchFileButton = uibutton(app.StimCtrl_ProtocollatchUIFigure, 'push');
            app.LoadProtocolBatchFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProtocolBatchFileButtonPushed, true);
            app.LoadProtocolBatchFileButton.Text = 'Load Protocol Batch File';
            app.PauseBatchButton = uibutton(app.StimCtrl_ProtocollatchUIFigure, 'push');
            app.PauseBatchButton.ButtonPushedFcn = createCallbackFcn(app, @PauseBatchButtonPushed, true);
            app.PauseBatchButton.Enable = 'off';
            app.PauseBatchButton.Text = 'Pause Batch';
            % Show the figure after all components are created
            app.StimCtrl_ProtocollatchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StimPHDultraCtrl_ProtocolBatch(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StimCtrl_ProtocollatchUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.StimCtrl_ProtocollatchUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimCtrl_ProtocollatchUIFigure)
        end
    end
end